classdef CircuitoModulador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_2                     matlab.ui.control.Label
        OpcionesdereproduccinLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        OpcionesdegrabacinLabel     matlab.ui.control.Label
        DuracinsSpinner             matlab.ui.control.Spinner
        DuracinsSpinnerLabel        matlab.ui.control.Label
        PausaButton                 matlab.ui.control.Button
        ReproducirButton            matlab.ui.control.Button
        SealDropDown                matlab.ui.control.DropDown
        SealDropDownLabel           matlab.ui.control.Label
        BitsMuestraSpinner          matlab.ui.control.Spinner
        BitsMuestraSpinnerLabel     matlab.ui.control.Label
        GrabaraudioButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        muestrasDeAudioOriginal="verificación";
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: GrabaraudioButton
        function GrabaraudioButtonPushed(app, event)
            paqueteGrabado = audiorecorder(48000, 16, 2);
            app.GrabaraudioButton.Text = "Grabando...";
            recordblocking(paqueteGrabado,app.DuracinsSpinner.Value);
            app.GrabaraudioButton.Text = "¡Grabado!";
            app.muestrasDeAudioOriginal = getaudiodata(paqueteGrabado);
            plot(app.UIAxes, app.muestrasDeAudioOriginal);
            pause(1);
            app.GrabaraudioButton.Text = "Grabar audio";
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            try
                if app.SealDropDown.Value == "Original"
                    sound(app.muestrasDeAudioOriginal, 48000);
                end
            catch
                app.ReproducirButton.Text = "No hay audio disponible";
                pause(2);
                app.ReproducirButton.Text = "Reproducir";
            end
        end

        % Button pushed function: PausaButton
        function PausaButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'tiempo')
            ylabel(app.UIAxes, 'voltaje')
            zlabel(app.UIAxes, 'Z')
            app.GrabaraudioButton = uibutton(app.UIFigure, 'push');
            app.GrabaraudioButton.ButtonPushedFcn = createCallbackFcn(app, @GrabaraudioButtonPushed, true);
            app.GrabaraudioButton.Text = 'Grabar audio';
            app.BitsMuestraSpinnerLabel = uilabel(app.UIFigure);
            app.BitsMuestraSpinnerLabel.Text = 'Bits/Muestra';
            app.BitsMuestraSpinner = uispinner(app.UIFigure);
            app.BitsMuestraSpinner.Limits = [1 Inf];
            app.BitsMuestraSpinner.Value = 1;
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.UIFigure);
            app.SealDropDown.Items = {'Original', 'Remuestreada', 'Original y Remuestreada'};
            app.SealDropDown.Value = 'Original';
            app.ReproducirButton = uibutton(app.UIFigure, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.PausaButton = uibutton(app.UIFigure, 'push');
            app.PausaButton.ButtonPushedFcn = createCallbackFcn(app, @PausaButtonPushed, true);
            app.PausaButton.Text = 'Pausa';
            app.DuracinsSpinnerLabel = uilabel(app.UIFigure);
            app.DuracinsSpinnerLabel.Text = 'Duración (s)';
            app.DuracinsSpinner = uispinner(app.UIFigure);
            app.DuracinsSpinner.Limits = [1 Inf];
            app.DuracinsSpinner.Value = 5;
            app.OpcionesdegrabacinLabel = uilabel(app.UIFigure);
            app.OpcionesdegrabacinLabel.Text = 'Opciones de grabación';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '____________________________________';
            app.OpcionesdereproduccinLabel = uilabel(app.UIFigure);
            app.OpcionesdereproduccinLabel.Text = 'Opciones de reproducción';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '____________________________________';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CircuitoModulador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end